classdef Dashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InterfaceTab                    matlab.ui.container.Tab
        BRAKEButton                     matlab.ui.control.Button
        LeftdistancetoobjectGaugeLabel  matlab.ui.control.Label
        LeftDistGauge                   matlab.ui.control.LinearGauge
        RightdistancetoobjectGaugeLabel  matlab.ui.control.Label
        RightDistGauge                  matlab.ui.control.LinearGauge
        DirectionGaugeLabel             matlab.ui.control.Label
        DirectionGauge                  matlab.ui.control.SemicircularGauge
        MotorspeedGaugeLabel            matlab.ui.control.Label
        MotorspeedGauge                 matlab.ui.control.SemicircularGauge
        CurrentvaluesLabel              matlab.ui.control.Label
        GoButton                        matlab.ui.control.Button
        StopdistancebeforeobjectcmEditFieldLabel  matlab.ui.control.Label
        stop_distance                   matlab.ui.control.NumericEditField
        Forward                         matlab.ui.control.Button
        Backward                        matlab.ui.control.Button
        Right                           matlab.ui.control.Button
        Left                            matlab.ui.control.Button
        ManualcontrolsLabel             matlab.ui.control.Label
        DataloggerTab                   matlab.ui.container.Tab
        DataloggerLabel                 matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        SavedataloggingButton           matlab.ui.control.Button
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global index_log
            index_log = 0;
            tic
        end

        % Button pushed function: BRAKEButton
        function BRAKEButtonPushed(app, event)
            EPOCommunications('transmit','D150');
            EPOCommunications('transmit','M135');
            checkSensors();
            pause(1);
            EPOCommunications('transmit','M150');
            checkSensors();
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            stopAtDistance(app.stop_distance.Value);
        end

        % Button pushed function: Forward
        function ForwardButtonPushed(app, event)
            EPOCommunications('transmit','M160');
            checkSensors();
            pause(1)
            EPOCommunications('transmit','M150');
            checkSensors();
        end

        % Button pushed function: Backward
        function BackwardButtonPushed(app, event)
            EPOCommunications('transmit','M140');
            checkSensors();
            pause(1)
            EPOCommunications('transmit','M150');
            checkSensors();
        end

        % Button pushed function: Right
        function RightButtonPushed(app, event)
            EPOCommunications('transmit','D200');
            EPOCommunications('transmit','M160');
            checkSensors();
            pause(1)
            EPOCommunications('transmit','D150');
            EPOCommunications('transmit','M150');
            checkSensors();
        end

        % Button pushed function: Left
        function LeftButtonPushed(app, event)
            EPOCommunications('transmit','D100');
            EPOCommunications('transmit','M160');
            checkSensors();
            pause(1)
            EPOCommunications('transmit','D150');
            EPOCommunications('transmit','M150');
            checkSensors();
        end

        % Button pushed function: SavedataloggingButton
        function SavedataloggingButtonPushed(app, event)
            saveDatalogging();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InterfaceTab = uitab(app.TabGroup);
            app.InterfaceTab.Title = 'Interface';
            app.InterfaceTab.Units = 'pixels';
            app.BRAKEButton = uibutton(app.InterfaceTab, 'push');
            app.BRAKEButton.ButtonPushedFcn = createCallbackFcn(app, @BRAKEButtonPushed, true);
            app.BRAKEButton.Text = 'BRAKE!';
            app.LeftdistancetoobjectGaugeLabel = uilabel(app.InterfaceTab);
            app.LeftdistancetoobjectGaugeLabel.Text = 'Left distance to object';
            app.LeftDistGauge = uigauge(app.InterfaceTab, 'linear');
            app.LeftDistGauge.Limits = [0 400];
            app.LeftDistGauge.MajorTicks = [0 50 100 150 200 250 300 350 400];
            app.RightdistancetoobjectGaugeLabel = uilabel(app.InterfaceTab);
            app.RightdistancetoobjectGaugeLabel.Text = 'Right distance to object';
            app.RightDistGauge = uigauge(app.InterfaceTab, 'linear');
            app.RightDistGauge.Limits = [0 400];
            app.RightDistGauge.MajorTicks = [0 50 100 150 200 250 300 350 400];
            app.DirectionGaugeLabel = uilabel(app.InterfaceTab);
            app.DirectionGaugeLabel.Text = 'Direction';
            app.DirectionGauge = uigauge(app.InterfaceTab, 'semicircular');
            app.DirectionGauge.Limits = [100 200];
            app.DirectionGauge.MajorTicks = [100 125 150 175 200];
            app.DirectionGauge.Value = 150;
            app.MotorspeedGaugeLabel = uilabel(app.InterfaceTab);
            app.MotorspeedGaugeLabel.Text = 'Motor speed';
            app.MotorspeedGauge = uigauge(app.InterfaceTab, 'semicircular');
            app.MotorspeedGauge.Limits = [135 165];
            app.MotorspeedGauge.MajorTicks = [135 140 145 150 155 160 165];
            app.MotorspeedGauge.Value = 150;
            app.CurrentvaluesLabel = uilabel(app.InterfaceTab);
            app.CurrentvaluesLabel.Text = 'Current values';
            app.GoButton = uibutton(app.InterfaceTab, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            app.StopdistancebeforeobjectcmEditFieldLabel = uilabel(app.InterfaceTab);
            app.StopdistancebeforeobjectcmEditFieldLabel.Text = 'Stop distance before object (cm)';
            app.stop_distance = uieditfield(app.InterfaceTab, 'numeric');
            app.stop_distance.Limits = [100 500];
            app.stop_distance.Value = 100;
            app.Forward = uibutton(app.InterfaceTab, 'push');
            app.Forward.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.Forward.Text = '↑';
            app.Backward = uibutton(app.InterfaceTab, 'push');
            app.Backward.ButtonPushedFcn = createCallbackFcn(app, @BackwardButtonPushed, true);
            app.Backward.Text = '↓';
            app.Right = uibutton(app.InterfaceTab, 'push');
            app.Right.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.Right.Text = '→';
            app.Left = uibutton(app.InterfaceTab, 'push');
            app.Left.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.Left.Text = '←';
            app.ManualcontrolsLabel = uilabel(app.InterfaceTab);
            app.ManualcontrolsLabel.Text = 'Manual controls:';
            app.DataloggerTab = uitab(app.TabGroup);
            app.DataloggerTab.Title = 'Data logger';
            app.DataloggerTab.Units = 'pixels';
            app.DataloggerLabel = uilabel(app.DataloggerTab);
            app.DataloggerLabel.Text = 'Data logger';
            app.UIAxes = uiaxes(app.DataloggerTab);
            title(app.UIAxes, 'Left distance to wall');
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Distance (cm)');
            app.UIAxes_2 = uiaxes(app.DataloggerTab);
            title(app.UIAxes_2, 'Right distance to wall');
            xlabel(app.UIAxes_2, 'Time (s)');
            ylabel(app.UIAxes_2, 'Distance (cm)');
            app.UIAxes_3 = uiaxes(app.DataloggerTab);
            title(app.UIAxes_3, 'Direction');
            xlabel(app.UIAxes_3, 'Time (s)');
            ylabel(app.UIAxes_3, 'Direction');
            app.UIAxes_4 = uiaxes(app.DataloggerTab);
            title(app.UIAxes_4, 'Motor speed');
            xlabel(app.UIAxes_4, 'Time (s)');
            ylabel(app.UIAxes_4, 'Motor speed');
            app.SavedataloggingButton = uibutton(app.DataloggerTab, 'push');
            app.SavedataloggingButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataloggingButtonPushed, true);
            app.SavedataloggingButton.Text = 'Save datalogging';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Dashboard()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end